classdef MD_TPMS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        ShellNetworksTab             matlab.ui.container.Tab
        GridLayout2                  matlab.ui.container.GridLayout
        Panel                        matlab.ui.container.Panel
        GENERATEButton               matlab.ui.control.Button
        GradeDistributionPanel       matlab.ui.container.Panel
        TextArea_2                   matlab.ui.control.TextArea
        Label_2                      matlab.ui.control.Label
        GridLayout3                  matlab.ui.container.GridLayout
        GradeMethodsButtonGroup      matlab.ui.container.ButtonGroup
        LevelsetValueButton          matlab.ui.control.RadioButton
        ThicknessButton              matlab.ui.control.RadioButton
        GridLayout9                  matlab.ui.container.GridLayout
        LevelsetValueEditField       matlab.ui.control.NumericEditField
        LevelsetValueEditFieldLabel  matlab.ui.control.Label
        GridLayout8                  matlab.ui.container.GridLayout
        MeshDensityEditField         matlab.ui.control.NumericEditField
        MeshDensityEditFieldLabel    matlab.ui.control.Label
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout7                  matlab.ui.container.GridLayout
        ZEditField                   matlab.ui.control.NumericEditField
        ZEditFieldLabel              matlab.ui.control.Label
        GridLayout6                  matlab.ui.container.GridLayout
        YEditField                   matlab.ui.control.NumericEditField
        YEditFieldLabel              matlab.ui.control.Label
        GridLayout5                  matlab.ui.container.GridLayout
        XEditField                   matlab.ui.control.NumericEditField
        XEditFieldLabel              matlab.ui.control.Label
        ImplicitFunctionFXYZPanel    matlab.ui.container.Panel
        TextArea                     matlab.ui.control.TextArea
        Label                        matlab.ui.control.Label
        SolidNetworksTab             matlab.ui.container.Tab
        GridLayout11                 matlab.ui.container.GridLayout
        Panel_4                      matlab.ui.container.Panel
        GENERATEButton_2             matlab.ui.control.Button
        GradeDistributionPanel_2     matlab.ui.container.Panel
        TextArea_4                   matlab.ui.control.TextArea
        ImplicitFunctionFXYZPanel_2  matlab.ui.container.Panel
        TextArea_3                   matlab.ui.control.TextArea
        GridLayout12                 matlab.ui.container.GridLayout
        TopologicalTypeButtonGroup   matlab.ui.container.ButtonGroup
        InverseTypeButton            matlab.ui.control.RadioButton
        NormalTypeButton             matlab.ui.control.RadioButton
        GridLayout17                 matlab.ui.container.GridLayout
        MeshDensityEditField_2       matlab.ui.control.NumericEditField
        MeshDensityEditField_2Label  matlab.ui.control.Label
        GridLayout13                 matlab.ui.container.GridLayout
        GridLayout16                 matlab.ui.container.GridLayout
        ZEditField_2                 matlab.ui.control.NumericEditField
        ZEditField_2Label            matlab.ui.control.Label
        GridLayout15                 matlab.ui.container.GridLayout
        YEditField_2                 matlab.ui.control.NumericEditField
        YEditField_2Label            matlab.ui.control.Label
        GridLayout14                 matlab.ui.container.GridLayout
        XEditField_2                 matlab.ui.control.NumericEditField
        XEditField_2Label            matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        ModelVisualizationPanel      matlab.ui.container.Panel
        GridLayout10                 matlab.ui.container.GridLayout
        Panel_3                      matlab.ui.container.Panel
        SAVESTLButton                matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        vertices;
        faces;% Description
        veri_textarea;
        veri_textarea2;
        veri_textarea3;
        veri_textarea4;
    end
    
    methods (Access = private)

        function grad = get_grade(app,type,x_,y_,z_,mesh_den)

            if type == 0
                value = join(app.TextArea_2.Value);
            else
                value = join(app.TextArea_4.Value);
            end
            dx = linspace(0,x_,x_*mesh_den);
            dy = linspace(0,y_,y_*mesh_den);
            dz = linspace(0,z_,z_*mesh_den);

            [X,Y,Z] = meshgrid(dx,dy,dz);


            den_offset = eval(value{1});
            if length(size(den_offset)) <= 2
                [n,m] = size(den_offset);

                grad = zeros(y_*mesh_den,x_*mesh_den,z_*mesh_den);

                if n == 1
                    if isscalar(den_offset)
                        grad = den_offset;

                    else
                        dz = linspace(0,z_,z_*mesh_den);
                        len = length(den_offset);
                        grad_index = interp1(linspace(0,z_,len),den_offset,dz);
                        for i = 1:z_*mesh_den
                            grad(:,:,i) = grad_index(i);

                        end
                    end
                else
                    dz = linspace(0,z_,z_*mesh_den);
                    dx = linspace(0,x_,x_*mesh_den);
                    [Zd, Xd] = meshgrid(dz,dx);

                    grad_index = interp2(linspace(0,x_,m),linspace(0,z_,n),den_offset,Xd,Zd);

                    for i = 1:y_*mesh_den
                        grad(i,:,:) = grad_index;

                    end
                end
            elseif length(size(den_offset)) == 3
                grad =den_offset;
            end  
       
        end


        function [range,tf] = get_range(~,expr)
            z = 1;
            x = 1;
            y = 1;
            mesh_den = 30;
            dx = linspace(0,x,x*mesh_den);
            dy = linspace(0,y,y*mesh_den);
            dz = linspace(0,z,z*mesh_den);
            [X,Y,Z] = meshgrid(dx,dy,dz);

            
            f = eval(expr{1});               
            range = [min(f,[],'all'), max(f,[],'all')];
            tf = 1;
        end


        function valid = VerifyExpr(~,textarea)
             expr = join(textarea.Value);
             d = linspace(0,1,5);
             [X,Y,Z] = meshgrid(d,d,d);
            
            try

                f = eval(expr{1});


                catch
                    warning('expr error.')
                    textarea.BackgroundColor = 'red';
                    valid=0;
                    return;

            end
            valid = 1;
            textarea.BackgroundColor = 'white';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.veri_textarea=1;
            app.veri_textarea2=1;
            app.veri_textarea3=0;
            app.veri_textarea4=0;
            app.TextArea.Value = "cos(2*pi*X).*sin(2*pi*Y)+cos(2*pi*Y).*sin(2*pi*Z)+cos(2*pi*Z).*sin(2*pi*X)";
            app.MeshDensityEditField.Value=40;
            app.XEditField.Value = 4; app.YEditField.Value=1; app.ZEditField.Value=4;
            app.LevelsetValueEditField.Value=0.2;
            app.LevelsetValueEditFieldLabel.Text="Thickness";
            app.LevelsetValueButton.Value=1;
%             app.TextArea_2.Value="[0.2 0.2 0.2 0.2 0.2;0.2 0.4 0.4 0.4 0.2;0.2 0.4 0.6 0.4 0.2;0.2 0.4 0.4 0.4 0.2;0.2 0.2 0.2 0.2 0.2]";
            app.TextArea_2.Value="[0 0 0; 0.2 0.2 0.2; 0.5 0.5 0.5; 0.9 0.9 0.9]";
            app.GENERATEButtonPushed();
        end

        % Selection changed function: GradeMethodsButtonGroup
        function GradeMethodsButtonGroupSelectionChanged(app, event)
            if app.LevelsetValueButton.Value == 1
                app.LevelsetValueEditFieldLabel.Text = 'Thickness';
            else
                app.LevelsetValueEditFieldLabel.Text = 'Level-set Value';

            end
            
            if app.veri_textarea
                expr = join(app.TextArea.Value);
                [range, tf] = app.get_range(expr);


                f = max(abs(range));
                app.GradeDistributionPanel.Title ="Grade Distribution";
                if app.ThicknessButton.Value == 1

                    app.GradeDistributionPanel.Title = "0"+"<"+app.GradeDistributionPanel.Title+"<"+num2str(f,'%.2f');

                else


                    app.GradeDistributionPanel.Title = num2str(-f,'%.2f')+"<"+app.GradeDistributionPanel.Title+"<"+num2str(f,'%.2f');

                end


            end
        end

        % Callback function: TextArea
        function TextAreaValueChanging(app, event)
%             changingValue = event.Value;
% 
% 
%             changingValue = join(changingValue);
%             changingValue = changingValue{1};
% 
%             if contains(changingValue,'x') || contains(changingValue,'y') || contains(changingValue,'z')
%                 app.TextArea.Tooltip="Enter the uppercase XYZ";
%                 app.TextArea.BackgroundColor = 'red';
%             else
%                 app.TextArea.Tooltip="";
%                 app.TextArea.BackgroundColor = 'white';
%             end
        end

        % Button pushed function: GENERATEButton
        function GENERATEButtonPushed(app, event)
            if app.veri_textarea && app.veri_textarea2
                cla(app.UIAxes);
                z_ = app.ZEditField.Value;
                x_ = app.XEditField.Value;
                y_ = app.YEditField.Value;
                mesh_den = app.MeshDensityEditField.Value;
                iso_value = app.LevelsetValueEditField.Value;
                grad = app.get_grade(0,x_,y_,z_,mesh_den);


                expr = join(app.TextArea.Value);
                if ~isinteger(x_) && ~isinteger(y_) && ~isinteger(z_)
                    dx = linspace(0,x_,x_*mesh_den);
                    dy = linspace(0,y_,y_*mesh_den);
                    dz = linspace(0,z_,z_*mesh_den);

                    [X,Y,Z] = meshgrid(dx,dy,dz);


                    %                     disp(expr{1})
                    f = eval(expr{1});


                    if isequal(size(f),size(X))

                        if app.ThicknessButton.Value == 1

                            f = abs(f-iso_value)-grad;
                            fv = isosurface(X,Y,Z,f,0);
                            fvc = isocaps(X,Y,Z,f,0,'enclose','below');

                        else
                            if iso_value==0
                                f = f-grad;
                                fv = isosurface(X,Y,Z,f,0);
                                fvc = [];

                            else
                                f = abs(f-grad)-iso_value;
                                fv = isosurface(X,Y,Z,f,0);
                                fvc = isocaps(X,Y,Z,f,0,'enclose','below');

                            end

                        end

                        %                 f=abs(cos(2*pi*X)+cos(2*pi*Y)+cos(2*pi*Z));
                        %                 %change level-set functions here


                        offset = length(fv.vertices);
                        if isstruct(fvc)
                            fv.vertices = [fv.vertices;fvc.vertices];
                            fv.faces = [fv.faces;fvc.faces+offset];
                            r_d = numel(find(f<=0))/numel(f);

                        else
                            r_d = 0;
                        end
                        title(app.UIAxes,"Relative Density: "+num2str(r_d,'%.3f'))
                        %                     [fv.vertices, I] = sortrows(fv.vertices);
                        %                     M = [true; any(diff(fv.vertices), 2)];
                        %                     fv.vertices = fv.vertices(M,:);
                        %                     I(I) = cumsum(M);
                        %                     fv.faces = I(fv.faces);
                        app.vertices = fv.vertices;
                        app.faces = fv.faces;
                        p = patch(app.UIAxes,fv);


                        %                     n = isonormals(X,Y,Z,f,fv.vertices);

                        p.FaceColor = 'red';
                        p.EdgeColor = 'none';
                        daspect(app.UIAxes,[1 1 1])
                        view(app.UIAxes,3);
                        axis(app.UIAxes,'tight');
                        camlight(app.UIAxes);
                        lighting(app.UIAxes,'gouraud');
                        %                     disp('OK')
                    end
                end
            end
        end

        % Callback function: TextArea_3
        function TextArea_3ValueChanging(app, event)
            
%             changingValue = event.Value;
% 
% 
%             changingValue = join(changingValue);
%             changingValue = changingValue{1};
% 
%             if contains(changingValue,'x') || contains(changingValue,'y') || contains(changingValue,'z')
%                 app.TextArea_3.Tooltip="Enter the uppercase XYZ";
%                 app.TextArea_3.BackgroundColor = 'red';
%             else
%                 app.TextArea_3.Tooltip="";
%                 app.TextArea_3.BackgroundColor = 'white';
%             end
        end

        % Button pushed function: GENERATEButton_2
        function GENERATEButton_2Pushed(app, event)
            if app.veri_textarea3&&app.veri_textarea4
                cla(app.UIAxes);
                z_ = app.ZEditField_2.Value;
                x_ = app.XEditField_2.Value;
                y_ = app.YEditField_2.Value;
                mesh_den = app.MeshDensityEditField_2.Value;
    
                grad = app.get_grade(1,x_,y_,z_,mesh_den);
    
    
                expr = join(app.TextArea_3.Value);
                if ~isinteger(x_) && ~isinteger(y_) && ~isinteger(z_)
                    dx = linspace(0,x_,x_*mesh_den);
                    dy = linspace(0,y_,y_*mesh_den);
                    dz = linspace(0,z_,z_*mesh_den);
    
                    [X,Y,Z] = meshgrid(dx,dy,dz);


                    f = eval(expr{1});


                    if isequal(size(f),size(X))
    
                        if app.NormalTypeButton.Value == 1
                            f = f-grad;
                        else
                            f = -(f-grad);
    
                        end
                        r_d = numel(find(f<=0))/numel(f);
                        title(app.UIAxes,"Relative Density: "+num2str(r_d,'%.3f'))
                        %                 f=abs(cos(2*pi*X)+cos(2*pi*Y)+cos(2*pi*Z)); 
    
                        fv = isosurface(X,Y,Z,f,0);
                        fvc = isocaps(X,Y,Z,f,0,'enclose','below');
                        offset = length(fv.vertices);
                        fv.vertices = [fv.vertices;fvc.vertices];
                        fv.faces = [fv.faces;fvc.faces+offset];
                        
    %                     [fv.vertices, I] = sortrows(fv.vertices);
    %                     M = [true; any(diff(fv.vertices), 2)];
    %                     fv.vertices = fv.vertices(M,:);
    %                     I(I) = cumsum(M);
    %                     fv.faces = I(fv.faces);
                        app.vertices = fv.vertices;
                        app.faces = fv.faces;
                        p = patch(app.UIAxes,fv);
    
    
%                         n = isonormals(X,Y,Z,f,fv.vertices);
    
                        p.FaceColor = 'red';
                        p.EdgeColor = 'none';
                        daspect(app.UIAxes,[1 1 1])
                        view(app.UIAxes,3);
                        axis(app.UIAxes,'tight');
                        camlight(app.UIAxes);
                        lighting(app.UIAxes,'gouraud');
                    end
                end
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            valid = VerifyExpr(app,app.TextArea);
            app.veri_textarea = valid;
            if valid
                expr = join(app.TextArea.Value);
                [range, tf] = app.get_range(expr);
    
                if tf
                    f = max(abs(range));
                    app.GradeDistributionPanel.Title ="Grade Distribution";
                    if app.ThicknessButton.Value == 1
                        
                        app.GradeDistributionPanel.Title = "0"+"<"+app.GradeDistributionPanel.Title+"<"+num2str(f,'%.2f');
                        
                    else
                        
    %                     
                        app.GradeDistributionPanel.Title = num2str(-f,'%.2f')+"<"+app.GradeDistributionPanel.Title+"<"+num2str(f,'%.2f');
    
                    end
    
                end
            end
        end

        % Value changed function: TextArea_3
        function TextArea_3ValueChanged(app, event)
           valid = app.VerifyExpr(app.TextArea_3);
           app.veri_textarea3=valid;

           if valid
               expr = join(app.TextArea_3.Value);
               [range, tf] = app.get_range(expr);


               f = max(abs(range));
               app.GradeDistributionPanel_2.Title ="Grade Distribution";


               app.GradeDistributionPanel_2.Title = num2str(-f,'%.2f')+"<"+app.GradeDistributionPanel_2.Title+"<"+num2str(f,'%.2f');


           end
        end

        % Button pushed function: SAVESTLButton
        function SAVESTLButtonPushed(app, event)
            tr = triangulation(app.faces, app.vertices);
            filter = {'*.stl'};
            [file, path] = uiputfile(filter);
            if file == 0
                return
            end
            old = cd(path);
            stlwrite(tr,file);
            cd(old);
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            app.veri_textarea2 = VerifyExpr(app,app.TextArea_2);
%             value = app.TextArea_2.Value;
            
        end

        % Value changed function: TextArea_4
        function TextArea_4ValueChanged(app, event)
%             value = app.TextArea_4.Value;
            app.veri_textarea4 = VerifyExpr(app,app.TextArea_4);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.ShellNetworksTab = uitab(app.TabGroup);
            app.ShellNetworksTab.Title = 'Shell Networks';
            app.GridLayout2 = uigridlayout(app.ShellNetworksTab);
            app.ImplicitFunctionFXYZPanel = uipanel(app.GridLayout2);
            app.ImplicitFunctionFXYZPanel.Title = 'Implicit Function F(X,Y,Z)';
            app.Label = uilabel(app.ImplicitFunctionFXYZPanel);
            app.Label.Text = {''; ''};
            app.TextArea = uitextarea(app.ImplicitFunctionFXYZPanel);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.ValueChangingFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.XEditFieldLabel = uilabel(app.GridLayout5);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.GridLayout5, 'numeric');
            app.XEditField.Limits = [1 Inf];
            app.XEditField.Value = 1;
            app.GridLayout6 = uigridlayout(app.GridLayout4);
            app.YEditFieldLabel = uilabel(app.GridLayout6);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.GridLayout6, 'numeric');
            app.YEditField.Limits = [1 Inf];
            app.YEditField.Value = 1;
            app.GridLayout7 = uigridlayout(app.GridLayout4);
            app.ZEditFieldLabel = uilabel(app.GridLayout7);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.GridLayout7, 'numeric');
            app.ZEditField.Limits = [1 Inf];
            app.ZEditField.Value = 1;
            app.GridLayout8 = uigridlayout(app.GridLayout3);
            app.MeshDensityEditFieldLabel = uilabel(app.GridLayout8);
            app.MeshDensityEditFieldLabel.Text = 'Mesh Density';
            app.MeshDensityEditField = uieditfield(app.GridLayout8, 'numeric');
            app.MeshDensityEditField.Limits = [20 Inf];
            app.MeshDensityEditField.RoundFractionalValues = 'on';
            app.MeshDensityEditField.Value = 20;
            app.GridLayout9 = uigridlayout(app.GridLayout3);
            app.LevelsetValueEditFieldLabel = uilabel(app.GridLayout9);
            app.LevelsetValueEditFieldLabel.Text = 'Level-set Value';
            app.LevelsetValueEditField = uieditfield(app.GridLayout9, 'numeric');
            app.GradeMethodsButtonGroup = uibuttongroup(app.GridLayout3);
            app.GradeMethodsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GradeMethodsButtonGroupSelectionChanged, true);
            app.GradeMethodsButtonGroup.Title = 'Grade Methods';
            app.ThicknessButton = uiradiobutton(app.GradeMethodsButtonGroup);
            app.ThicknessButton.Text = 'Thickness';
            app.ThicknessButton.Value = true;
            app.LevelsetValueButton = uiradiobutton(app.GradeMethodsButtonGroup);
            app.LevelsetValueButton.Text = 'Level-set Value';
            app.GradeDistributionPanel = uipanel(app.GridLayout2);
            app.GradeDistributionPanel.Title = 'Grade Distribution';
            app.Label_2 = uilabel(app.GradeDistributionPanel);
            app.Label_2.Text = '';
            app.TextArea_2 = uitextarea(app.GradeDistributionPanel);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.Panel = uipanel(app.GridLayout2);
            app.GENERATEButton = uibutton(app.Panel, 'push');
            app.GENERATEButton.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButtonPushed, true);
            app.GENERATEButton.Text = 'GENERATE';
            app.SolidNetworksTab = uitab(app.TabGroup);
            app.SolidNetworksTab.Title = 'Solid Networks';
            app.GridLayout11 = uigridlayout(app.SolidNetworksTab);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.XEditField_2Label = uilabel(app.GridLayout14);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.GridLayout14, 'numeric');
            app.XEditField_2.Limits = [1 Inf];
            app.XEditField_2.Value = 1;
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.YEditField_2Label = uilabel(app.GridLayout15);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.GridLayout15, 'numeric');
            app.YEditField_2.Limits = [1 Inf];
            app.YEditField_2.Value = 1;
            app.GridLayout16 = uigridlayout(app.GridLayout13);
            app.ZEditField_2Label = uilabel(app.GridLayout16);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.GridLayout16, 'numeric');
            app.ZEditField_2.Limits = [1 Inf];
            app.ZEditField_2.Value = 1;
            app.GridLayout17 = uigridlayout(app.GridLayout12);
            app.MeshDensityEditField_2Label = uilabel(app.GridLayout17);
            app.MeshDensityEditField_2Label.Text = 'Mesh Density';
            app.MeshDensityEditField_2 = uieditfield(app.GridLayout17, 'numeric');
            app.MeshDensityEditField_2.Limits = [20 Inf];
            app.MeshDensityEditField_2.Value = 20;
            app.TopologicalTypeButtonGroup = uibuttongroup(app.GridLayout12);
            app.TopologicalTypeButtonGroup.Title = 'Topological Type';
            app.NormalTypeButton = uiradiobutton(app.TopologicalTypeButtonGroup);
            app.NormalTypeButton.Text = 'Normal Type';
            app.NormalTypeButton.Value = true;
            app.InverseTypeButton = uiradiobutton(app.TopologicalTypeButtonGroup);
            app.InverseTypeButton.Text = 'Inverse Type';
            app.ImplicitFunctionFXYZPanel_2 = uipanel(app.GridLayout11);
            app.ImplicitFunctionFXYZPanel_2.Title = 'Implicit Function F(X,Y,Z)';
            app.TextArea_3 = uitextarea(app.ImplicitFunctionFXYZPanel_2);
            app.TextArea_3.ValueChangedFcn = createCallbackFcn(app, @TextArea_3ValueChanged, true);
            app.TextArea_3.ValueChangingFcn = createCallbackFcn(app, @TextArea_3ValueChanging, true);
            app.GradeDistributionPanel_2 = uipanel(app.GridLayout11);
            app.GradeDistributionPanel_2.Title = 'Grade Distribution';
            app.TextArea_4 = uitextarea(app.GradeDistributionPanel_2);
            app.TextArea_4.ValueChangedFcn = createCallbackFcn(app, @TextArea_4ValueChanged, true);
            app.Panel_4 = uipanel(app.GridLayout11);
            app.GENERATEButton_2 = uibutton(app.Panel_4, 'push');
            app.GENERATEButton_2.ButtonPushedFcn = createCallbackFcn(app, @GENERATEButton_2Pushed, true);
            app.GENERATEButton_2.Text = 'GENERATE';
            app.RightPanel = uipanel(app.GridLayout);
            app.ModelVisualizationPanel = uipanel(app.RightPanel);
            app.ModelVisualizationPanel.Title = 'Model Visualization';
            app.GridLayout10 = uigridlayout(app.ModelVisualizationPanel);
            app.UIAxes = uiaxes(app.GridLayout10);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_3 = uipanel(app.GridLayout10);
            app.SAVESTLButton = uibutton(app.Panel_3, 'push');
            app.SAVESTLButton.ButtonPushedFcn = createCallbackFcn(app, @SAVESTLButtonPushed, true);
            app.SAVESTLButton.Text = 'SAVE STL ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MD_TPMS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end